classdef BabyCryDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BabyCryDetectionUIFigure  matlab.ui.Figure
        BabyCryLamp               matlab.ui.control.Lamp
        BabyCryLampLabel          matlab.ui.control.Label
        MicrophoneListeningLamp   matlab.ui.control.Lamp
        Label                     matlab.ui.control.Label
        StopButton                matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        adw % Audio Device Reader object
        data % data
        buff % Buffer
        listenEnable % 
        net = load('trainedNet.mat'); % specify the path to mat file

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            fs = 44100;
            totalSamples = fs * 5;
            app.adw = audioDeviceReader('SampleRate',fs);
            app.buff = dsp.AsyncBuffer("Capacity", totalSamples);
            app.listenEnable = true;
            app.MicrophoneListeningLamp.Color = 'g';
            while(app.listenEnable)
                audioIn = app.adw();
                
                % write audio data to buffer
                write(app.buff,audioIn);

                if app.buff.NumUnreadSamples == totalSamples
                    if isempty(app.data)
                        app.data = read(app.buff, totalSamples);
                    else
                        % Shift sound data and add last to buffer
                        app.data = circshift(app.data,fs);
                        app.data = [app.data(1:totalSamples-fs); read(app.buff, fs)];
                    end
                    
                    % Extract mel spectogram from audio
                    [S,cF,t] = melSpectrogram(app.data, fs);

                    % Convert to dB for plotting
                    S = 10*log10(S+eps); 

                    %Figure visible off
                    f = figure('Visible', 'off');
                    ah = axes(f, 'Units','normalized','Position',[0 0 1 1]);
                    surf(t,cF,S,'EdgeColor','none');
                    view([0,90]);
                    axis(ah, 'tight');

                    % Get image
                    convertedImage = imresize(getframe(gcf).cdata, [224,224]);

                    % Classify melspectogram image of sound with CNN network
                    sounds = classify(app.net.trainedNet, convertedImage);
                    imshow(convertedImage,'Parent',app.UIAxes);
                    
                    % baby cry lamp off
                    app.BabyCryLamp.Color = 'w';
                    for i = 1:length(sounds)
                        if sounds(i) == "crying_baby"
                            % baby cry lamp on
                            app.BabyCryLamp.Color = 'r';
                        end
                    end
                    
                end
                drawnow();
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.listenEnable = false;
            app.MicrophoneListeningLamp.Color = 'w';
            app.BabyCryLamp.Color = 'w';
            app.TextArea.Value = '';
        end

        % Close request function: BabyCryDetectionUIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.buff);
            delete(app.adw);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BabyCryDetectionUIFigure = uifigure('Visible', 'off');
            app.BabyCryDetectionUIFigure.Name = 'Baby Cry Detection';
            app.BabyCryDetectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.BabyCryDetectionUIFigure);
            title(app.UIAxes, 'Melspectogram')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {};
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.StartButton = uibutton(app.BabyCryDetectionUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.BabyCryDetectionUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Label = uilabel(app.BabyCryDetectionUIFigure);
            app.Label.Text = 'Microphone Listening';
            app.MicrophoneListeningLamp = uilamp(app.BabyCryDetectionUIFigure);
            app.BabyCryLampLabel = uilabel(app.BabyCryDetectionUIFigure);
            app.BabyCryLampLabel.Text = 'Baby Cry';
            app.BabyCryLamp = uilamp(app.BabyCryDetectionUIFigure);
            % Show the figure after all components are created
            app.BabyCryDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BabyCryDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BabyCryDetectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BabyCryDetectionUIFigure)
        end
    end
end